classdef PingPong < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		PingPongUIFigure             matlab.ui.Figure
		GridLayout                   matlab.ui.container.GridLayout
		LeftPanel                    matlab.ui.container.Panel
		GridLayout2                  matlab.ui.container.GridLayout
		DummyTouchCheckBox           matlab.ui.control.CheckBox
		StimulusDropDown             matlab.ui.control.DropDown
		StimulusDropDownLabel        matlab.ui.control.Label
		ArduinoBEditField            matlab.ui.control.EditField
		ArduinoBEditFieldLabel       matlab.ui.control.Label
		ArduinoAEditField            matlab.ui.control.EditField
		ArduinoAEditFieldLabel       matlab.ui.control.Label
		StartBEditField              matlab.ui.control.NumericEditField
		StartBEditFieldLabel         matlab.ui.control.Label
		StartAEditField              matlab.ui.control.NumericEditField
		StartAEditFieldLabel         matlab.ui.control.Label
		SubjectBEditField            matlab.ui.control.EditField
		SubjectBEditFieldLabel       matlab.ui.control.Label
		SubjectAEditField            matlab.ui.control.EditField
		SubjectAEditFieldLabel       matlab.ui.control.Label
		WallThicknessEditField       matlab.ui.control.NumericEditField
		WallThicknessEditFieldLabel  matlab.ui.control.Label
		FloorEditField               matlab.ui.control.NumericEditField
		FloorEditFieldLabel          matlab.ui.control.Label
		RightWallEditField           matlab.ui.control.NumericEditField
		RightWallEditFieldLabel      matlab.ui.control.Label
		LeftWallEditField            matlab.ui.control.NumericEditField
		LeftWallEditFieldLabel       matlab.ui.control.Label
		ExperimentDropDown           matlab.ui.control.DropDown
		ExperimentDropDownLabel      matlab.ui.control.Label
		BallSizeEditField            matlab.ui.control.NumericEditField
		BallSizeEditFieldLabel       matlab.ui.control.Label
		SensitivityEditField         matlab.ui.control.NumericEditField
		SensitivityEditFieldLabel    matlab.ui.control.Label
		ElasticityEditField          matlab.ui.control.NumericEditField
		ElasticityEditFieldLabel     matlab.ui.control.Label
		AirResistanceEditField       matlab.ui.control.NumericEditField
		AirResistanceEditFieldLabel  matlab.ui.control.Label
		STARTButton                  matlab.ui.control.Button
		QUITButton                   matlab.ui.control.Button
		RightPanel                   matlab.ui.container.Panel
		GridLayout3                  matlab.ui.container.GridLayout
		UIaxis1                      matlab.ui.control.UIAxes
		UIaxis2                      matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: STARTButton
		function STARTButtonPushed(app, event)
			in.startA = app.StartAEditField.Value;
			in.startB = app.StartBEditField.Value;
			in.ballSize = app.BallSizeEditField.Value;
			in.airR = app.AirResistanceEditField.Value;
			in.elasticity = app.ElasticityEditField.Value;
			in.sensitivity = app.SensitivityEditField.Value;
			in.floor = app.FloorEditField.Value;
			in.leftW = app.LeftWallEditField.Value;
			in.rightW = app.RightWallEditField.Value;
			in.wallT = app.WallThicknessEditField.Value;
			in.subjecta = app.SubjectAEditField.Value;
			in.subjectb = app.SubjectBEditField.Value;
			in.arduinoa = app.ArduinoAEditField.Value;
			in.arduinob = app.ArduinoBEditField.Value;
			in.image = app.StimulusDropDown.Value;
			in.axis1 = app.UIaxis1;
			in.axis2 = app.UIaxis2;
			in.dummy = app.DummyTouchCheckBox.Value;
			runPingPong(in);
		end

		% Button pushed function: QUITButton
		function QUITButtonPushed(app, event)
			delete(app)
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.PingPongUIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {477, 477};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {401, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.PingPongUIFigure = uifigure('Visible', 'off');
			app.PingPongUIFigure.Name = 'Ping Pong';
			app.PingPongUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.PingPongUIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.GridLayout2 = uigridlayout(app.LeftPanel);
			app.QUITButton = uibutton(app.GridLayout2, 'push');
			app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
			app.QUITButton.Text = 'QUIT';
			app.STARTButton = uibutton(app.GridLayout2, 'push');
			app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
			app.STARTButton.Text = 'START';
			app.AirResistanceEditFieldLabel = uilabel(app.GridLayout2);
			app.AirResistanceEditFieldLabel.Text = 'Air Resistance';
			app.AirResistanceEditField = uieditfield(app.GridLayout2, 'numeric');
			app.AirResistanceEditField.Value = 0.1;
			app.ElasticityEditFieldLabel = uilabel(app.GridLayout2);
			app.ElasticityEditFieldLabel.Text = 'Elasticity';
			app.ElasticityEditField = uieditfield(app.GridLayout2, 'numeric');
			app.ElasticityEditField.Value = 0.8;
			app.SensitivityEditFieldLabel = uilabel(app.GridLayout2);
			app.SensitivityEditFieldLabel.Text = 'Sensitivity';
			app.SensitivityEditField = uieditfield(app.GridLayout2, 'numeric');
			app.SensitivityEditField.Value = 6;
			app.BallSizeEditFieldLabel = uilabel(app.GridLayout2);
			app.BallSizeEditFieldLabel.Text = 'Ball Size';
			app.BallSizeEditField = uieditfield(app.GridLayout2, 'numeric');
			app.BallSizeEditField.Value = 5;
			app.ExperimentDropDownLabel = uilabel(app.GridLayout2);
			app.ExperimentDropDownLabel.Text = 'Experiment';
			app.ExperimentDropDown = uidropdown(app.GridLayout2);
			app.ExperimentDropDown.Items = {'Control', 'Cooperation', 'Competition'};
			app.ExperimentDropDown.Value = 'Control';
			app.LeftWallEditFieldLabel = uilabel(app.GridLayout2);
			app.LeftWallEditFieldLabel.Text = 'Left Wall';
			app.LeftWallEditField = uieditfield(app.GridLayout2, 'numeric');
			app.LeftWallEditField.Value = -20;
			app.RightWallEditFieldLabel = uilabel(app.GridLayout2);
			app.RightWallEditFieldLabel.Text = 'Right Wall';
			app.RightWallEditField = uieditfield(app.GridLayout2, 'numeric');
			app.RightWallEditField.Value = 20;
			app.FloorEditFieldLabel = uilabel(app.GridLayout2);
			app.FloorEditFieldLabel.Text = 'Floor';
			app.FloorEditField = uieditfield(app.GridLayout2, 'numeric');
			app.FloorEditField.Value = 5;
			app.WallThicknessEditFieldLabel = uilabel(app.GridLayout2);
			app.WallThicknessEditFieldLabel.Text = 'Wall Thickness';
			app.WallThicknessEditField = uieditfield(app.GridLayout2, 'numeric');
			app.WallThicknessEditField.Value = 1;
			app.SubjectAEditFieldLabel = uilabel(app.GridLayout2);
			app.SubjectAEditFieldLabel.Text = 'Subject A';
			app.SubjectAEditField = uieditfield(app.GridLayout2, 'text');
			app.SubjectAEditField.Value = 'Ping';
			app.SubjectBEditFieldLabel = uilabel(app.GridLayout2);
			app.SubjectBEditFieldLabel.Text = 'Subject B';
			app.SubjectBEditField = uieditfield(app.GridLayout2, 'text');
			app.SubjectBEditField.Value = 'Pong';
			app.StartAEditFieldLabel = uilabel(app.GridLayout2);
			app.StartAEditFieldLabel.Text = 'Start A';
			app.StartAEditField = uieditfield(app.GridLayout2, 'numeric');
			app.StartAEditField.Value = -10;
			app.StartBEditFieldLabel = uilabel(app.GridLayout2);
			app.StartBEditFieldLabel.Text = 'Start B';
			app.StartBEditField = uieditfield(app.GridLayout2, 'numeric');
			app.StartBEditField.Value = 10;
			app.ArduinoAEditFieldLabel = uilabel(app.GridLayout2);
			app.ArduinoAEditFieldLabel.Text = 'Arduino A';
			app.ArduinoAEditField = uieditfield(app.GridLayout2, 'text');
			app.ArduinoAEditField.Value = '/dev/ttyACM0';
			app.ArduinoBEditFieldLabel = uilabel(app.GridLayout2);
			app.ArduinoBEditFieldLabel.Text = 'Arduino B';
			app.ArduinoBEditField = uieditfield(app.GridLayout2, 'text');
			app.ArduinoBEditField.Value = '/dev/ttyACM0';
			app.StimulusDropDownLabel = uilabel(app.GridLayout2);
			app.StimulusDropDownLabel.Text = 'Stimulus';
			app.StimulusDropDown = uidropdown(app.GridLayout2);
			app.StimulusDropDown.Items = {'ball1.png', 'ball2.png', 'ball3.png', 'moon.png', 'star.png', 'heptagon.png'};
			app.StimulusDropDown.Value = 'ball3.png';
			app.DummyTouchCheckBox = uicheckbox(app.GridLayout2);
			app.DummyTouchCheckBox.Text = 'Dummy Touch';
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout3 = uigridlayout(app.RightPanel);
			app.UIaxis2 = uiaxes(app.GridLayout3);
			title(app.UIaxis2, 'Performance')
			xlabel(app.UIaxis2, 'Trial')
			ylabel(app.UIaxis2, 'Correct')
			zlabel(app.UIaxis2, 'Z')
			app.UIaxis1 = uiaxes(app.GridLayout3);
			title(app.UIaxis1, 'Ball Position')
			xlabel(app.UIaxis1, 'X')
			ylabel(app.UIaxis1, 'Y')
			zlabel(app.UIaxis1, 'Z')
			% Show the figure after all components are created
			app.PingPongUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = PingPong

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.PingPongUIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.PingPongUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.PingPongUIFigure)
		end
	end
end